classdef BehaviorBox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        InputControlPanel               matlab.ui.container.Panel
        InputDelayRespond               matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        InputDelayStart                 matlab.ui.control.NumericEditField
        DuringTrialEditFieldLabel       matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        IntertrialMalCheckBox           matlab.ui.control.CheckBox
        FinishlineCheckBox              matlab.ui.control.CheckBox
        RoundUpEditField                matlab.ui.control.NumericEditField
        RoundUpEditFieldLabel           matlab.ui.control.Label
        RoundupchoiceCheckBox           matlab.ui.control.CheckBox
        TurnMagEditField                matlab.ui.control.NumericEditField
        TurnMagEditFieldLabel           matlab.ui.control.Label
        StimulusvariablesPanel          matlab.ui.container.Panel
        SaveComputerSettingsButton      matlab.ui.control.Button
        LoadComputerSettingsButton      matlab.ui.control.Button
        BoxNum                          matlab.ui.control.NumericEditField
        BoxEditFieldLabel               matlab.ui.control.Label
        edit22                          matlab.ui.control.EditField
        text72                          matlab.ui.control.Label
        edit17                          matlab.ui.control.EditField
        edit19                          matlab.ui.control.EditField
        edit20                          matlab.ui.control.EditField
        edit21                          matlab.ui.control.EditField
        SpotlightaroundstimulusCheckBox  matlab.ui.control.CheckBox
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        ReadyCuecolorLabel              matlab.ui.control.Label
        BackgroundEditField             matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel        matlab.ui.control.Label
        Stimulus_DimColor               matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel_2      matlab.ui.control.Label
        FlashColor                      matlab.ui.control.NumericEditField
        EditField_5Label_2              matlab.ui.control.Label
        LinebrightnessEditField         matlab.ui.control.NumericEditField
        LinebrightnessEditFieldLabel    matlab.ui.control.Label
        BetweenSpotlight                matlab.ui.control.NumericEditField
        BetweenSpotlight_textlabel      matlab.ui.control.Label
        SpotlightbrightnessEditField    matlab.ui.control.NumericEditField
        SpotlightbrightnessEditFieldLabel  matlab.ui.control.Label
        SegmentSpacingEditField         matlab.ui.control.NumericEditField
        SegmentSpacingEditFieldLabel    matlab.ui.control.Label
        SegmentlengthEditField          matlab.ui.control.NumericEditField
        SegmentlengthEditFieldLabel     matlab.ui.control.Label
        SegmentthicknessEditField       matlab.ui.control.NumericEditField
        SegmentthicknessEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        edit51_3                        matlab.ui.control.EditField
        edit51_2                        matlab.ui.control.EditField
        edit50_2                        matlab.ui.control.EditField
        OnlyCorrectCheckBox             matlab.ui.control.CheckBox
        edit51                          matlab.ui.control.EditField
        text122                         matlab.ui.control.Label
        edit50                          matlab.ui.control.EditField
        text121                         matlab.ui.control.Label
        checkbox19                      matlab.ui.control.CheckBox
        FlashLinesOnlyCheckBox          matlab.ui.control.CheckBox
        Stimulus_type                   matlab.ui.control.DropDown
        text65                          matlab.ui.control.Label
        SubjectPanel                    matlab.ui.container.Panel
        checkbox7                       matlab.ui.control.CheckBox
        WeightgEditField                matlab.ui.control.EditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        Strain                          matlab.ui.control.EditField
        text13                          matlab.ui.control.Label
        Inv                             matlab.ui.control.EditField
        Investigator                    matlab.ui.control.Label
        text86                          matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        AirPuffduringIntertrialCheckBox  matlab.ui.control.CheckBox
        puff_2_valves                   matlab.ui.control.CheckBox
        puff_on                         matlab.ui.control.CheckBox
        Puff_pulses                     matlab.ui.control.EditField
        puff_valve_1_time               matlab.ui.control.EditField
        AirButton                       matlab.ui.control.Button
        SecBwPulse                      matlab.ui.control.NumericEditField
        secbwPulsesEditFieldLabel       matlab.ui.control.Label
        edit11_2                        matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        edit11                          matlab.ui.control.EditField
        checkbox2                       matlab.ui.control.CheckBox
        checkbox15                      matlab.ui.control.CheckBox
        RightPulse                      matlab.ui.control.NumericEditField
        Rrewardtime                     matlab.ui.control.EditField
        RButton_2                       matlab.ui.control.Button
        JuiceCheckBox                   matlab.ui.control.CheckBox
        LeftPulse                       matlab.ui.control.NumericEditField
        Lrewardtime                     matlab.ui.control.EditField
        LButton_2                       matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        edit15                          matlab.ui.control.EditField
        edit14                          matlab.ui.control.EditField
        text35                          matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        PersistincorrectCheckBox        matlab.ui.control.CheckBox
        EditField_4                     matlab.ui.control.NumericEditField
        PersistcorrectCheckBox          matlab.ui.control.CheckBox
        checkbox3                       matlab.ui.control.CheckBox
        checkbox5                       matlab.ui.control.CheckBox
        checkbox20                      matlab.ui.control.CheckBox
        SideBiassEditField              matlab.ui.control.NumericEditField
        SideBiassEditField_2Label       matlab.ui.control.Label
        edit10                          matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        HoldStill                       matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        edit8                           matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        edit6                           matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        Pokes_ignored_time              matlab.ui.control.EditField
        checkbox4                       matlab.ui.control.CheckBox
        uipanel1                        matlab.ui.container.Panel
        prob_list                       matlab.ui.control.TextArea
        checkbox22                      matlab.ui.control.CheckBox
        DistractorsSpinner              matlab.ui.control.Spinner
        DistractorsSpinnerLabel         matlab.ui.control.Label
        edit49                          matlab.ui.control.EditField
        text119                         matlab.ui.control.Label
        edit48                          matlab.ui.control.EditField
        text117                         matlab.ui.control.Label
        edit33                          matlab.ui.control.EditField
        text90                          matlab.ui.control.Label
        Stimulus_side                   matlab.ui.control.DropDown
        text89                          matlab.ui.control.Label
        Box_Input_type                  matlab.ui.control.DropDown
        text66                          matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        OutputTab                       matlab.ui.container.Tab
        MsgBox                          matlab.ui.control.TextArea
        PerformanceTab                  matlab.ui.container.Tab
        TimersTab                       matlab.ui.container.Tab
        AllTimeTab                      matlab.ui.container.Tab
        WeightWaterTab                  matlab.ui.container.Tab
        OtherUnusedTab                  matlab.ui.container.Tab
        EquipmentPanel                  matlab.ui.container.Panel
        RESETALLButton_2                matlab.ui.control.StateButton
        OpenBothButton                  matlab.ui.control.StateButton
        OpenRButton                     matlab.ui.control.StateButton
        OpenLButton                     matlab.ui.control.StateButton
        checkbox21                      matlab.ui.control.CheckBox
        checkbox8                       matlab.ui.control.CheckBox
        checkbox9                       matlab.ui.control.CheckBox
        SoundonlyonerrorCheckBox        matlab.ui.control.CheckBox
        edit34                          matlab.ui.control.EditField
        text92                          matlab.ui.control.Label
        checkbox17                      matlab.ui.control.CheckBox
        uipanel8                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        pushbutton3                     matlab.ui.control.Button
        FlashifwrongCheckBox            matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        checkbox6                       matlab.ui.control.CheckBox
        uipanel13                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        edit45                          matlab.ui.control.EditField
        text103                         matlab.ui.control.Label
        edit44                          matlab.ui.control.EditField
        text102                         matlab.ui.control.Label
        uipanel12                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        edit53                          matlab.ui.control.EditField
        text124                         matlab.ui.control.Label
        edit52                          matlab.ui.control.EditField
        text123                         matlab.ui.control.Label
        edit42                          matlab.ui.control.EditField
        text100                         matlab.ui.control.Label
        edit41                          matlab.ui.control.EditField
        text99                          matlab.ui.control.Label
        edit40                          matlab.ui.control.EditField
        text98                          matlab.ui.control.Label
        edit39                          matlab.ui.control.EditField
        text97                          matlab.ui.control.Label
        text96                          matlab.ui.control.Label
        edit38                          matlab.ui.control.EditField
        text95                          matlab.ui.control.Label
        edit37                          matlab.ui.control.EditField
        text94                          matlab.ui.control.Label
        edit35                          matlab.ui.control.EditField
        checkbox18                      matlab.ui.control.CheckBox
        edit23                          matlab.ui.control.EditField
        checkbox1                       matlab.ui.control.CheckBox
        popupmenu9                      matlab.ui.control.DropDown
        uipanel11                       matlab.ui.container.Panel
        edit32                          matlab.ui.control.EditField
        edit30                          matlab.ui.control.EditField
        edit29                          matlab.ui.control.EditField
        checkbox13                      matlab.ui.control.CheckBox
        edit28                          matlab.ui.control.EditField
        popupmenu4                      matlab.ui.control.DropDown
        edit26                          matlab.ui.control.EditField
        edit25                          matlab.ui.control.EditField
        pushbutton19                    matlab.ui.control.Button
        text85                          matlab.ui.control.Label
        text84                          matlab.ui.control.Label
        text83                          matlab.ui.control.Label
        text82                          matlab.ui.control.Label
        text81                          matlab.ui.control.Label
        text79                          matlab.ui.control.Label
        pushbutton11                    matlab.ui.control.Button
        text17                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        Subject                         matlab.ui.control.EditField
        text87                          matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        pushbutton10                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        pushbutton2                     matlab.ui.control.StateButton
        SkipButton2                     matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        FFButton                        matlab.ui.control.StateButton
        pushbutton1                     matlab.ui.control.Button
        TestBoxButton                   matlab.ui.control.Button
        pushbutton4                     matlab.ui.control.Button
        text15                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text1                           matlab.ui.control.Label
    end

    properties (Access = public)
    end
    methods (Access = private)
        function TL = CreateGraphs(~, P)
            %Create the TiledLayout in the Panel for all of the graphs...
            %This fcn is moved to BBdata
            r = 3; c = 4;
            TL = tiledlayout(P, r, c, ...
                'Padding','tight', ...
                'TileSpacing','tight');
            % Create Binned Perf
            BP = nexttile(TL, [1 4]);
            BP.Tag = 'Axes_BinnedPerf';
            title(BP, 'Binned Performance')
            BP.Toolbar.Visible = 'off';
            BP.TickLabelInterpreter = 'none';
            BP.YLim = [0 1];
            BP.TickLength = [0 0];
            BP.YTick = [0 0.5 0.75 1];
            BP.YTickLabelRotation = 90;
            BP.YTickLabel = {''; '50%'; '75%'; ''};
            BP.YGrid = 'on';
            BP.XTick = [];
            BP.NextPlot = 'add';
            BP.Box = 'on';
            BP.BoxStyle = 'full';
            BP.PickableParts = 'none';
            % Create TH
            TH = nexttile(TL, [1 2]);
            TH.Tag = 'Axes_TrialHistory';
            title(TH, 'Trial History')
            TH.Toolbar.Visible = 'off';
            TH.YLim = [0 1];
            TH.TickLength = [0 0];
            TH.YTick = [0.2 0.5 0.8];
            TH.YTickLabelRotation = 0;
            TH.YTickLabel = {'T'; 'W'; 'C'};
            TH.XTick = [];
            TH.NextPlot = 'add';
            TH.Box = 'on';
            TH.BoxStyle = 'full';
            TH.HitTest = 'off';
            TH.PickableParts = 'none';


            % Create LP
            LP = nexttile(TL, [1 2]);
            LP.Tag = 'Axes_LevelCount';
            title(LP, 'Level Performance')
            LP.Toolbar.Visible = 'off';
            LP.YLim = [0 1];
            LP.XTick = [];
            LP.YTick = [0 0.1 0.5 0.75 1];
            LP.YTickLabelRotation = 90;
            LP.YTickLabel = {''; '#'; '50%'; '75%'; ''};
            LP.YGrid = 'on';
            LP.TickDir = 'none';
            LP.NextPlot = 'add';
            LP.Box = 'on';
            LP.BoxStyle = 'full';
            LP.PickableParts = 'none';


            % Create SB
            SB = nexttile(TL, [1 1]);
            SB.Tag = 'Axes_SideBias';
            title(SB, 'Side Bias History')
            SB.Toolbar.Visible = 'off';
            SB.YLim = [0 1];
            SB.TickLength = [0 0];
            SB.XTick = [];
            SB.YTick = [];
            SB.NextPlot = 'add';
            SB.Box = 'on';
            SB.BoxStyle = 'full';
            SB.PickableParts = 'none';

            % Create axes6
            ST = nexttile(TL, [1 1]);
            ST.Tag = 'Axes_TimeToStart';
            title(ST, 'Time to Start')
            ST.Toolbar.Visible = 'off';
            ST.TickLength = [0 0];
            ST.XTick = [];
            ST.YTick = [];
            ST.NextPlot = 'add';
            ST.Box = 'on';
            ST.BoxStyle = 'full';
            ST.PickableParts = 'none';

            % Create axes7
            RT = nexttile(TL, [1 1]);
            RT.Tag = 'Axes_ResponseTime';
            title(RT, 'Response Time')
            RT.Toolbar.Visible = 'off';
            RT.TickLength = [0 0];
            RT.XTick = [];
            RT.YTick = [];
            RT.NextPlot = 'add';
            RT.Box = 'on';
            RT.BoxStyle = 'full';
            RT.PickableParts = 'none';

            % Create axes8
            DT = nexttile(TL, [1 1]);
            DT.Tag = 'Axes_DrinkTime';
            title(DT, 'Drink Dwell Time')
            DT.Toolbar.Visible = 'off';
            DT.TickLength = [0 0];
            DT.XTick = [];
            DT.YTick = [];
            DT.NextPlot = 'add';
            DT.Box = 'on';
            DT.BoxStyle = 'full';
            DT.PickableParts = 'none';
        end
        function PreviewStimulus(app, hObject, eventdata, handles)
            app.text1.Text = 'Previewing stimulus...';
            BB = evalin('base','BB');
            try
                BB.getGUI();
                BB.TestStimulus();
            catch err
                BB.unwrapError(err)
                BB.cleanUP;
            end
        end

        function SaveGuiInputs(app, handles)
            %The old way to save/load settings used the input method and the stimulus
            %type to name the file. Instead, let's use the mouse strain and cage number
            %to be specific to that animal:
            %Find out the stain
            Strain = handles.Strain.String;
            Subject = handles.Subject.String;
            Investigator = handles.Investigator.String;
            InputType = handles.Box_Input_type.Value;
            switch InputType
                case 1
                    Input='OneLever';
                case 2
                    Input='TwoLevers';
                case 3
                    Input='NosePoke';
                case 4
                    Input='RotatingBall';
                case 5
                    Input ='LickPort';
                case 6
                    Input = 'Wheel';
                case 7
                    Input = 'GoNoGo';
                case 8
                    Input = 'Keyboard';
            end
            %get all settings
            %Setting_Struct = getGuiInputAsStructSave(app, handles);
            props = properties(app);
            values = cell(1, length(props));
            visibilities = cell(1,length(props));
            graphs = {};
            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                %                 if strcmp(propName, 'edit19') || strcmp(propName, 'edit17') || strcmp(propName, 'edit20') || strcmp(propName, 'edit21') || strcmp(propName, 'edit22') || strcmp(propName, 'edit59') || strcmp(propName, 'edit13') || strcmp(propName, 'edit31')
                %                     continue
                %                 end
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Value')
                    visibilities{i} = app.(propName).Visible;
                end
                if isprop(property, 'XLim') %Find the axes
                    graphs{end+1} = app.(propName);
                end
            end
            %To save the 6 graphs:
            set(0,'showhiddenhandles','on')
            %h is the handle for the 6 graphs.
            h = findobj(gcf,'type','axes');
            SaveMode = ['_',Strain,'_', Subject];
            saveasname = ['SavedSettings', SaveMode,'.mat'];
            dataDir = ['D:\Dropbox (Dropbox @RU)\Gilbert Lab\BehaviorBoxData\Data\' Investigator filesep Input filesep];
            filelist = dir(fullfile(dataDir, '**\*.*'));
            filelist = filelist([filelist.isdir]);
            filelist(ismember({filelist.name},{'.', '..'})) = [];
            names = {filelist.name};
            whichfolder = contains(names, Subject);
            if sum(whichfolder) == 0 %~any(whichfolder)
                mkdir([dataDir Strain filesep Subject filesep])
                savefolder = [dataDir Strain filesep Subject filesep];
            elseif sum(whichfolder) > 1
                where = [filelist(find(whichfolder,1)).folder filesep];
                savefolder = pwd; %In case there is an error below
                try
                    [~,savefolder] = uiputfile('*.*' , 'Choose folder to save training settings:' , [where saveasname]);
                    if ~contains(savefolder, 'Settings')
                        savefolder = [savefolder 'Settings' filesep];
                    end
                    if ~exist(savefolder, 'dir')
                        mkdir(savefolder);
                    end
                end
            elseif sum(whichfolder) == 1 %any(whichfolder)
                savefolder = [filelist(whichfolder).folder filesep filelist(whichfolder).name filesep 'Settings' filesep];
                if ~exist(savefolder, 'dir')
                    mkdir(savefolder);
                end
            end
            try
                save([savefolder saveasname], 'props', 'values', 'visibilities', 'h');
            catch ME
                where = [filelist(find(whichfolder,1)).folder filesep];
                [~,savefolder] = uiputfile('*.*' , 'Choose folder to save training data' , [where saveasname]);
                save([file path], 'props', 'values', 'visibilities', 'h');
            end
            fprintf(['Settings Saved as: %s\n',saveasname]);
            app.text1.Text = ['Settings Saved as: ',saveasname];
        end

        function changeGUIvalue(app, field_handle, change)
            Old_Entry=str2double(get(field_handle,'String'));
            New_Entry=Old_Entry+change;
            if New_Entry<0
                New_Entry=0;
            end
            set(field_handle,'String',num2str(New_Entry));
        end

        function loadGuiInputAsStruct(app, handles, ~)
            %Read settings from interface:
            Sub = {handles.Subject.String};
            Invest = handles.Investigator.String;
            Inp = app.Box_Input_type.Value;
            Str = handles.Strain.String;
            BBData = BehaviorBoxData("Inv", Invest, "Inp", Inp, "Sub",Sub, "Str", Str, find=1);
            try
                BBData.AnalyzeAllData();
                LevOptions = "1, 1:"+BBData.MaxLevel;
                app.prob_list.Value = LevOptions; drawnow
                Ax = BBData.plotLvByDayOneAxis();
                Ax.PickableParts = "none";
                clo(app.AllTimeTab)
                copyobj(Ax, app.AllTimeTab)
                close(Ax.Parent.Parent)
                t = BBData.PlotWeightAndWater();
                clo(app.WeightWaterTab)
                copyobj(t, app.WeightWaterTab)
                close(t.Parent)
            end
            assignin("base", "BBData", BBData)
            try
                if isempty(BBData.fds) || numel(BBData.fds.Files) == 0
                    disp('No saved settings found for current subject, using current values');
                    return
                else
                    filename = BBData.fds.Files{end};
                    tree = split(filename, filesep);
                end
                Set = BBData.loadedData{end,3}.Settings;
                Set = Set(end);
                handles.Strain.String = Set.Strain;
                BBData.Str = Set.Strain;
            catch
                msg = 'Problem loading, check files... using current values';
                disp(msg);
                app.text1.Text = msg;
            end
            try
                names = fieldnames(Set);
                props = properties(app);
                types = cellfun(@(x) app.(x).Type, props, 'UniformOutput', false);
                skiptypes = {'button', 'figure', 'text', 'label', 'panel', 'annotationpane', 'axes', 'tab', 'uigridlayout'};
                skipnames = {'Strain', 'Investigator'};
                props = props(~contains(types, skiptypes));
                Tags = cellfun(@(x) app.(x).Tag, props, 'UniformOutput', false); %Loop through the tags and find what matches in the Set structure instead of the old way
                for p = Tags'
                    try
                        property = handles.(p{:});
                        Tag = p{:};
                        if any(matches(skipnames, Tag))
                            continue
                        end
                        if ~isfield(Set, Tag)
                            switch 1
                                case contains(p, "Stimulus_")
                                    Tag = erase(property.Tag, "Stimulus_");
                                    try
                                        Tag = erase(Tag, "Color");
                                    end
                                case contains(p, "ReadyCue_")
                                    Tag = erase(property.Tag, "_");
                                case contains(p, "Box_")
                                    Tag = erase(property.Tag, "Box_");
                            end
                            if ~isfield(Set, Tag)
                                posTag = names(contains(names, Tag));
                                if numel(posTag) == 1
                                    Tag = posTag{:};
                                elseif numel(posTag) > 1
                                    Tag = cell2mat(posTag(matches(posTag, p)));
                                elseif numel(posTag) == 0
                                    %names(contains(names, p))
                                end
                            end
                        end
                        try
                            property.Value = Set.(Tag);
                        catch
                            if isempty(property.Value)
                                property.String = Set.(Tag);
                            else
                                property.Value = Set.(posTag);
                            end
                        end
                    catch err
                        fprintf("Error - " + p{:} + "\n")
                    end
                end
                pdfName = [erase(filename, '.mat') '.pdf'];
                if ispc
                    winopen(pdfName)
                    winopen(cell2mat(join(tree(1:end-1), filesep)))
                end
            catch err
                disp(err.message)
            end
            text = "Settings synced.";
            fprintf('%s\n',text)
            app.text1.Text = text;
        end

        function LoadComputerSpecifics(app)
            %Get filename for loading
            if ~ismember('a',evalin('base','who'))
                %Check available ports for unclaimed ardino
                switch true
                    case ispc %PCs in lab have multiple arduinos connected
                        %Settings will be specific to the COM number
                        sl = serialportlist("Available");
                        COMSlist = {};
                        for i = 1:length(sl)
                            COMSlist{end+1} = convertStringsToChars(sl(i));
                            if i < length(sl)
                                COMSlist{end+1} = ', ';
                            elseif i == length(sl)
                                COMSlist{end+1} = '.';
                            end
                        end
                        COM = app.edit22.Value;
                    case ismac
                        COM = app.edit22.Value;
                end
            elseif ismember('a',evalin('base','who'))
                COM = str2double(regexp(evalin('base','a.Port'),'[\d.]+','match'));
            end
            if isempty(COM)
                tree = split(pwd, filesep);
                tree{end} = 'BehaviorBox';
                tree{end} = '';
                newPath = join(tree, filesep);
                folder = cell2mat(newPath);
                files = dir(folder);
                which = contains({files.name}, 'Settings','IgnoreCase',true);
                if sum(which) == 1
                    filename = files(which).name;
                end
            elseif ispc && isempty(COM)
                fprintf('Please use the Device Manager to find the COM port of the Arduino to proceed.\n');
                app.text1.Text = 'Please use the Device Manager to find the COM port of the Arduino to proceed.';
            else
                switch true
                    case ispc
                        User = getenv('username');
                        if ~exist(['C:\Users\' User], 'dir')
                            User = 'wsnyd'; %This is for the one computer in the corner which has a problem with the name
                        end
                        dataDir = ['C:\Users\' User '\Desktop\BehaviorBox\'];
                        if ~exist(dataDir, 'dir')
                            fprintf('No settings found, please check the desktop for the BehaviorBox folder.\n');
                            app.text1.Text = 'No settings found, please check the desktop for the BehaviorBox folder.';
                        else
                            files = dir(dataDir);
                            files(ismember({files.name},{'.', '..'})) = [];
                            names = {files.name};
                            filename = ['NComputer Settings' num2str(COM)];
                            whichfile = contains(names, filename);
                            if ~any(whichfile)
                                fprintf('No settings found in the folder, please check the COM number.\n');
                                app.text1.Text = 'No settings found in the folder, please check the COM number.';
                            end
                            folder = dataDir;
                        end
                    case ismac
                        tree = split(pwd, filesep);
                        newPath = join(tree(1:(end-1)), filesep);
                        folder = [newPath filesep 'BehaviorBox' filesep];
                        folder = cell2mat(folder);
                        filename = 'NComputer Settings';
                end
            end
            try
                load([folder filename], 'SavedComputerSpecifics')
                app.edit19.Value = num2str(SavedComputerSpecifics.X);
                app.edit17.Value = num2str(SavedComputerSpecifics.Y);
                app.edit20.Value = num2str(SavedComputerSpecifics.W);
                app.edit21.Value = num2str(SavedComputerSpecifics.H);
                app.checkbox15.Value = SavedComputerSpecifics.TwoValves;
                app.Lrewardtime.Value = num2str(SavedComputerSpecifics.L);
                app.Rrewardtime.Value = num2str(SavedComputerSpecifics.R);
                app.checkbox2.Value = SavedComputerSpecifics.VarPulse;
                app.edit11_2.Value = num2str(SavedComputerSpecifics.PulseMax);
                app.edit11.Value = num2str(SavedComputerSpecifics.PulseMin);
                app.LeftPulse.Value = double(SavedComputerSpecifics.PLeft);
                app.RightPulse.Value = double(SavedComputerSpecifics.PRight);
                app.puff_valve_1_time.Value = num2str(SavedComputerSpecifics.Air);
                app.Puff_pulses.Value = num2str(SavedComputerSpecifics.PAir);
                app.BoxNum.Value = SavedComputerSpecifics.Boxnum;
                if isfield(SavedComputerSpecifics, 'Input')
                    app.Box_Input_type.Value = SavedComputerSpecifics.Input{:};
                end
                app.text1.Text = 'Computer settings loaded.';
                fprintf('Computer settings loaded.\n')
            catch
                fprintf('Computer settings not found, check COM and Arduino and look for Settings on the Desktop.\n')
            end
        end

        function SaveComputerSpecifics(app)
            %Settings will be specific to the COM number
            COM = app.edit22.Value;
            SavedComputerSpecifics = struct();
            if isempty(COM)
                fprintf('Please use the Device Manager to find the COM port of the Arduino to proceed.\n');
            else
                SavedComputerSpecifics.Input = {app.Box_Input_type.Value};
                SavedComputerSpecifics.X = str2double(app.edit19.Value);
                SavedComputerSpecifics.Y = str2double(app.edit17.Value);
                SavedComputerSpecifics.W = str2double(app.edit20.Value);
                SavedComputerSpecifics.H = str2double(app.edit21.Value);
                SavedComputerSpecifics.TwoValves = app.checkbox15.Value;
                SavedComputerSpecifics.L = str2double(app.Lrewardtime.Value);
                SavedComputerSpecifics.R = str2double(app.Rrewardtime.Value);
                SavedComputerSpecifics.VarPulse = app.checkbox2.Value;
                SavedComputerSpecifics.PulseMax = str2double(app.edit11_2.Value);
                SavedComputerSpecifics.PulseMin = str2double(app.edit11.Value);
                SavedComputerSpecifics.PLeft = double(app.LeftPulse.Value);
                SavedComputerSpecifics.PRight = double(app.RightPulse.Value);
                SavedComputerSpecifics.Air = str2double(app.puff_valve_1_time.Value);
                SavedComputerSpecifics.PAir = str2double(app.Puff_pulses.Value);
                SavedComputerSpecifics.Boxnum = double(app.BoxNum.Value);
                if ispc
                    User = getenv('username');
                    if ~exist(['C:\Users\' User])
                        User = 'wsnyd'; %This is for the one computer in the corner which has a problem with the name
                    end
                    dataDir = ['C:\Users\' User '\Desktop\BehaviorBox\'];
                    if ~exist(dataDir, 'dir')
                        mkdir(dataDir);
                        savefolder = dataDir;
                    else
                        savefolder = dataDir;
                    end
                    saveasname = ['NComputer Settings' num2str(COM)];
                elseif ismac
                    tree = split(pwd, filesep);
                    newPath = join(tree(1:(end-1)), filesep);
                    folder = [newPath filesep 'BehaviorBox' filesep];
                    folder = cell2mat(folder);
                    if ~exist(folder, 'dir')
                        mkdir(folder);
                        savefolder = folder;
                    else
                        savefolder = folder;
                    end
                    saveasname = 'NComputer Settings';
                end
                save([savefolder saveasname], 'SavedComputerSpecifics')
            end
            app.text1.Text = 'Computer settings saved.';
            fprintf('Computer settings saved.\n')
        end

        function connectArduino(app, COMSnum)
            try
                switch nargin
                    case 1
                        COMSnum = num2str(app.edit22.Value);
                end
                if ispc
                    comsnum = ['COM' COMSnum];
                elseif ismac
                    return
                    comsnum = COMSnum;
                end
                a = arduino(comsnum,'Uno','Libraries',{'I2C' 'Servo' 'SPI','RotaryEncoder'});
                configurePin(a, 'D2', 'Unset')
                configurePin(a, 'D3', 'Unset')
                assignin("base", "a", a)
                app.text1.Text = ['Arduino at COM' COMSnum ' claimed and copied into base workspace.' ];
                fprintf('Arduino at COM%s claimed and copied into base workspace.\n', COMSnum)
            catch
                fprintf('Check the USB connection.\n')
            end
        end

        function printHardwareconnections(~, handles)
            current_selection_stimulus=get(handles.Box_Input_type,'Value');
            Two_ports=get(handles.Box_Two_ports,'Value');
            Two_puffs = 0;
            Air_Puff_Penalty = 1;
            fprintf('------SET UP-------\n')
            switch current_selection_stimulus
                case 1
                    fprintf('Contour crude stimulus\n')
                case 2
                    fprintf('Contour fine stimulus\n')
                case 3
                    fprintf('Square Vs Open Box crude stimulus\n')
                case 4
                    fprintf('Square Vs Open Box fine stimulus\n')
                case 5
                    fprintf('Match Target stimulus\n')
                case 11
                    fprintf('Contour Density: Identify the dashed-line contour\n')
            end
            current_selection_input=get(handles.Box_Input_type,'Value');
            switch current_selection_input
                case 1
                    fprintf('One Lever Input\n')
                    fprintf('- connect One Lever Input to Arduino pin analog 2\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 2
                    fprintf('Two Lever Input\n')
                    fprintf('- connect Left Lever Input to Arduino pin D2\n')
                    fprintf('- connect Right Lever Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 3
                    fprintf('Three Pokes Input\n')
                    fprintf('- connect Center Poke Input to Arduino pin D3\n')
                    fprintf('- connect Left Poke Input to Arduino pin D2\n')
                    fprintf('- connect Right Poke Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    if Air_Puff_Penalty == 1
                        if Two_puffs == 0
                            fprintf('- connect puff valve output to Arduino pin D11\n')
                        else
                            fprintf('- connect left puff valve output to Arduino pin D11\n')
                            fprintf('- connect right puff valve output to Arduino pin D12\n')
                        end
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 4
                    fprintf('Rotating Ball Input\n')
                    fprintf('- connect Left Turn Input to Arduino pin D2\n')
                    fprintf('- connect Right Turn Input to Arduino pin D7\n')
                    if ~Two_ports
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- connect reset output to Arduino pin D4\n')
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 5
                    fprintf('Lick Sensor Input\n')
                    fprintf('- connect Center Lick Sensor Input to Arduino pin D3\n')
                    fprintf('- connect Left Lick Sensor Input to Arduino pin D2\n')
                    fprintf('- connect Right Lick Sensor Input to Arduino pin D7\n')
                    fprintf('- connect Left water reward valve to Arduino pin D8\n')
                    fprintf('- connect Right water reward valve to Arduino pin D6\n')
                    fprintf('- connect AirPuff penalty valve to Arduino pin D11\n')
                case 6
                    fprintf('Wheel Input: Accu-Coder Rotary Encoder \n')
                    fprintf('- connect Encoder Power (white wire) to Arduino +5V \n')
                    fprintf('- connect Encoder Ground (black wire) to Arduino GND \n')
                    fprintf('- connect A (brown wire) to Arduino pin D2 \n')
                    fprintf('- connect B (red wire) to Arduino pin D3 \n')
                    fprintf('- connect Water reward valve to Arduino pin D6\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
            end
            fprintf('------------------\n');
        end
        function printWelcomeMsg(~, handles)
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
            fprintf("888888b.            888                        d8b                  888888b.                     ");
            fprintf('\n');
            fprintf('888  "88b           888                        Y8P                  888  "88b                    ');
            fprintf('\n');
            fprintf("888  .88P           888                                             888  .88P                    ");
            fprintf('\n');
            fprintf("8888888K.   .d88b.  88888b.   8888b.  888  888 888  .d88b.  888d888 8888888K.   .d88b.  888  888 ");
            fprintf('\n');
            fprintf('888  "Y88b d8P  Y8b 888 "88b     "88b 888  888 888 d88""88b 888P"   888  "Y88b d88""88b `Y8bd8P');
            fprintf('\n');
            fprintf('888    888 88888888 888  888 .d888888 Y88  88P 888 888  888 888     888    888 888  888   X88K   ');
            fprintf('\n');
            fprintf('888   d88P Y8b.     888  888 888  888  Y8bd8P  888 Y88..88P 888     888   d88P Y88..88P .d8""8b. ');
            fprintf('\n');
            fprintf('8888888P"   "Y8888  888  888 "Y888888   Y88P   888  "Y88P"  888     8888888P"   "Y88P"  888  888 ');
            fprintf('\n');
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BehaviorBox_OpeningFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            %OPENING FUNCTION
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to BehaviorBox (see VARARGIN)
            handles.output = hObject;
            % **************** ADD THIS SECTION ******************
            %from: https://www.mathworks.com/matlabcentral/answers/482510-annotationpane-handle-appearing-in-guide-guis-with-panel-axes-in-r2019b
            % Check if scribeOverlay is a field and that it contains an annotation pane
            if isfield(handles,'scribeOverlay') && isa(handles.scribeOverlay(1),'matlab.graphics.shape.internal.AnnotationPane')
                delete(handles.scribeOverlay);
                handles = rmfield(handles, 'scribeOverlay');
            end
            % **********************  END ************************
            % Update handles structure
            guidata(hObject, handles);
            printWelcomeMsg(app, handles)
        % Make Diary to log stdout:
            diary off
            app.MsgBox.Value = ''; drawnow
            try
                delete('BBAppoutput.txt')
            end
            diary('BBAppoutput.txt');
            sl = serialportlist("Available");
            sl(contains(sl, 'Bluetooth', IgnoreCase=true)) = [];
            sl(contains(sl, 'COM1', IgnoreCase=true)) = [];
            COMSlist = sl;
            if ~isempty(COMSlist)
                if ispc
                    COMSnum = COMSlist{1}(regexp(COMSlist{1}, '\d'));
                elseif ismac
                    whichPort = contains(sl, 'cu.usbmodem');
                    if sum(whichPort) == 1
                        COMSnum = sl(whichPort);
                    end
                end
                app.edit22.Value = COMSnum;
            elseif ismember('a',evalin('base','who')) %If there are no free COM ports, check if Arduino is already connected in base workspace
                COMNum = evalin('base','a.Port');
                COMNum = COMNum(regexp(COMNum, '\d'));
                app.edit22.Value = COMNum;
                LoadComputerSpecifics(app)
                app.text1.Text = ['Arduino at COM ' COMNum ' copied from base workspace.' ];
                fprintf('Arduino at COM%s copied from base workspace.\n', COMNum)
            else
                app.text1.Text = 'No available COMs found, check USB connections or use Keyboard Input.' ;
            end
            LoadComputerSpecifics(app)
            printHardwareconnections(app, handles) %Print out welcome message and some instructions
            app.text1.Text = 'Connecting Arduino...' ;
            connectArduino(app)
            if app.Box_Input_type.Value == "NosePoke"
                BB = BehaviorBoxNose(handles, app);
            BB.SetUpHardware();
            elseif app.Box_Input_type.Value == "Wheel"
                BB = BehaviorBoxWheel(handles, app);
            end
            assignin("base", "BB", BB)
        % Clean Up:
            diary off
            app.MsgBox.Value = fileread('BBAppoutput.txt');
            BB.toggleButtonsOnOff(BB.Buttons, 1)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run regular trials
            %START BUTTON
            try
                evalin('base','clc')
                if app.Box_Input_type.Value == "NosePoke"
                    trialObject = BehaviorBoxNose(handles, app);
                elseif app.Box_Input_type.Value == "Wheel"
                    trialObject = BehaviorBoxWheel(handles, app);
                    %trialObject.SetUpHardware;
                end
                trialObject.RunTrials();
                SaveComputerSpecifics(app);
                if ismember('a',evalin('base','who'))
                    app.text1.Text = 'Arduino returned to base workspace.';
                end
            catch err
                disp(err.message);
                trialObject.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %TRAINING BUTTON
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run training
            try
                %Constructor functions, SetUp arduino and variables
                ThisTrial = BehaviorBoxSuper(handles, app);
                %Start the training
                ThisTrial.RunTraining();
                %if autosave, save
                if get(handles.AutoLoadSave,'Value') ==1
                    SaveGuiInputs(app, handles);
                    SaveComputerSpecifics(app)
                end
            catch err
                disp(err.message);
                ThisTrial.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %PRINT CONNECTION AND PINS NEEDED
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printHardwareconnections(app, handles)
        end

        % Callback function
        function pushbutton6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit17, 10);
        end

        % Callback function
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit17, -10);
        end

        % Callback function
        function pushbutton8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit19, -10);
        end

        % Callback function
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            changeGUIvalue(app, handles.edit19, 10);
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % show PREVIEW STIMULUS BUTTON
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PreviewStimulus(app, hObject, eventdata, handles);
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % SAVE SUBJECT SETTINGS
            % hObject    handle to pushbutton11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SaveGuiInputs(app, handles);
        end

        % Callback function
        function pushbutton12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit20, -10);
        end

        % Callback function
        function pushbutton13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit21, -10);
        end

        % Callback function
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit20, 10);
        end

        % Callback function
        function pushbutton15_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            changeGUIvalue(app, handles.edit21, 10);
        end

        % Button pushed function: LoadButton
        function LoadButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % LOAD SUBJECT SETTINGS
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: pushbutton19
        function pushbutton19_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % ORIENTATION TUNING ONLY
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                %run orientationTuning
                ThisTrial = BehaviorBoxSub2(handles);
                ThisTrial.RunOrientationTuning();
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: TestBoxButton
        function TestBoxButtonPushed(app, event)
            %To test water output:
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            SaveComputerSpecifics(app)
            % TEST BOX
            try
                if ismember('BB', evalin('base','who'))
                    BB = evalin('base','BB');
                    BB.TextBox();
                else
                    BehaviorBox_OpeningFcn(app)
                    BB.TextBox();
                end
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: LoadComputerSettingsButton
        function LoadComputerSettingsButtonPushed(app, event)
            % LOAD COMPUTER SETTINGS
            %To load the computer specific settings (such as window size
            %and position and water drop sizes)
            LoadComputerSpecifics(app);
        end

        % Button pushed function: SaveComputerSettingsButton
        function SaveComputerSettingsButtonPushed(app, event)
            % SAVE COMPUTER SETTINGS
            %To save the computer specific settings (such as window size
            %and position and water drop sizes) to a folder on the
            %desktop
            SaveComputerSpecifics(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            evalin('base', 'clear a BB');
            delete(findobj("Type", "figure", "Name", "Stimulus"))
            delete(findobj("Type", "figure", "Name", "Graphs"))
        end

        % Button pushed function: LButton_2
        function LButton_2Pushed(app, event)
            % L TEST WATER BUTTON
            a = evalin('base', 'a');
            Valve = 'D8'; %Left
            Time = str2double(app.Lrewardtime.Value);
            Rep = app.LeftPulse.Value;
            SecBwPulse = app.SecBwPulse.Value;
            % command to pulse valve
            for i = 1:Rep
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                pause(SecBwPulse)
            end
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            % R TEST WATER BUTTON
            a = evalin('base', 'a');
            Valve = 'D6'; %Right
            Time = str2double(app.Rrewardtime.Value);
            % command to pulse valve
            for i = 1:app.RightPulse.Value
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                pause(app.SecBwPulse.Value)
            end
        end

        % Button pushed function: AirButton
        function AirButtonPushed(app, event)
            % AIR TEST BUTTON
            a = evalin('base', 'a');
            Valve = 'D11'; %AIR
            Time = str2double(app.puff_valve_1_time.Value);
            Rep = str2double(app.Puff_pulses.Value);
            % command to pulse valve
            for i = 1:Rep
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                pause(0.2)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESET BUTTON
            evalin('base', 'clear hidden; clc')
            evalin('base', 'clear a BB')
            BehaviorBox_OpeningFcn(app)
        end

        % Value changed function: OpenLButton
        function OpenLButtonValueChanged(app, event)
            value = app.OpenLButton.Value;
            a = evalin('base', 'a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value)
        end

        % Value changed function: OpenRButton
        function OpenRButtonValueChanged(app, event)
            value = app.OpenRButton.Value;
            a = evalin('base', 'a');
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value)
        end

        % Value changed function: OpenBothButton
        function OpenBothButtonValueChanged(app, event)
            value = app.OpenBothButton.Value;
            a = evalin('base', 'a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value)
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value)
        end

        % Value changed function: RESETALLButton_2
        function RESETALLButtonPushed(app, event)
            app.RESETALLButton_2.Text = 'Resetting...';
            evalin('base', 'clear all hidden classes; clc')
            evalin('base', '!git pull')
            BehaviorBox_OpeningFcn(app)
            app.RESETALLButton_2.Value = 0;
            app.RESETALLButton_2.Text = 'Reset ALL!';
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            value = app.Subject.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            loadGuiInputAsStruct(app, handles, 0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'colorcube');
            app.figure1.Name = 'BehaviorBox';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'idle';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Left';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Right ';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = '-';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = '-';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Rewards';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'total correct:';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '0';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = '--';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Time(min):';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = '-';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'choices';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'difficulty';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = '0';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Trial Number';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'PrintSetup';
            app.pushbutton4.Tooltip = 'show all connections required in command window';
            app.pushbutton4.Text = 'Setup';
            app.TestBoxButton = uibutton(app.figure1, 'push');
            app.TestBoxButton.ButtonPushedFcn = createCallbackFcn(app, @TestBoxButtonPushed, true);
            app.TestBoxButton.Tag = 'Water';
            app.TestBoxButton.Text = 'Test Box';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'Start';
            app.pushbutton1.Tooltip = 'Start the complete training';
            app.pushbutton1.Text = 'Start';
            app.FFButton = uibutton(app.figure1, 'state');
            app.FFButton.Tag = 'FastForward';
            app.FFButton.Tooltip = {'Click this button to fast-forward through the current pause interval'};
            app.FFButton.Icon = fullfile(pathToMLAPP, 'Forward_font_awesome.svg.png');
            app.FFButton.Text = '';
            app.PauseButton = uibutton(app.figure1, 'state');
            app.PauseButton.Tag = 'Pause';
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'pause.png');
            app.PauseButton.Text = '';
            app.SkipButton2 = uibutton(app.figure1, 'state');
            app.SkipButton2.Tag = 'Skip';
            app.SkipButton2.Icon = fullfile(pathToMLAPP, 'skip.png');
            app.SkipButton2.Text = '';
            app.pushbutton2 = uibutton(app.figure1, 'state');
            app.pushbutton2.Tag = 'Stop';
            app.pushbutton2.Tooltip = 'Stop all';
            app.pushbutton2.Text = 'Stop';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'ResetButton';
            app.ResetButton.Tooltip = {'Click to clear memory and reconnect Arduino.'};
            app.ResetButton.Text = 'Reset';
            app.pushbutton10 = uibutton(app.figure1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'ShowStim';
            app.pushbutton10.Tooltip = {'This fucking button is broken.'};
            app.pushbutton10.Text = 'Preview';
            app.LoadButton = uibutton(app.figure1, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_Callback, true);
            app.LoadButton.Tag = 'LoadButton';
            app.LoadButton.Text = 'Load Subject Settings';
            app.text87 = uilabel(app.figure1);
            app.text87.Tag = 'text87';
            app.text87.Text = 'Subject';
            app.Subject = uieditfield(app.figure1, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tag = 'Subject';
            app.Subject.Tooltip = 'Enter animal number here';
            app.Subject.Value = 'w';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '0';
            app.pushbutton11 = uibutton(app.figure1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'SaveButton';
            app.pushbutton11.Text = 'Save Subject Settings';
            app.TabGroup = uitabgroup(app.figure1);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.GridLayout = uigridlayout(app.SetupTab);
            app.GridLayout.Tag = 'GridLayout';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Level & Side';
            app.uipanel1.Tag = 'uipanel1';
            app.text66 = uilabel(app.uipanel1);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Input:';
            app.Box_Input_type = uidropdown(app.uipanel1);
            app.Box_Input_type.Items = {'One Lever', 'Two Levers', 'NosePoke', 'Rotating Ball', 'Lick Sensor', 'Wheel', 'Lick Go/No-Go', 'Keyboard'};
            app.Box_Input_type.Tag = 'Box_Input_type';
            app.Box_Input_type.Tooltip = 'choose input';
            app.Box_Input_type.Value = 'NosePoke';
            app.text89 = uilabel(app.uipanel1);
            app.text89.Tag = 'text89';
            app.text89.Text = 'Side:';
            app.Stimulus_side = uidropdown(app.uipanel1);
            app.Stimulus_side.Items = {'Random', 'Left only', 'Right only', 'Alternate repeat', 'Alternate random', 'Side-Bias Correction', 'Pseudo-Random', 'Keyboard'};
            app.Stimulus_side.Tag = 'Stimulus_side';
            app.Stimulus_side.Tooltip = 'choose stimulus';
            app.Stimulus_side.Value = 'Random';
            app.text90 = uilabel(app.uipanel1);
            app.text90.Tag = 'text90';
            app.text90.Text = 'Alternate Int:';
            app.edit33 = uieditfield(app.uipanel1, 'text');
            app.edit33.Tag = 'Change_alternate';
            app.edit33.Tooltip = 'how many single choices are used to calculate % performance';
            app.edit33.Value = '3';
            app.text117 = uilabel(app.uipanel1);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Alt. Random:';
            app.edit48 = uieditfield(app.uipanel1, 'text');
            app.edit48.Tag = 'MinRandAlt';
            app.edit48.Tooltip = 'What the starting opacity is in complete training';
            app.edit48.Value = '2';
            app.text119 = uilabel(app.uipanel1);
            app.text119.Tag = 'text119';
            app.text119.Text = '<';
            app.edit49 = uieditfield(app.uipanel1, 'text');
            app.edit49.Tag = 'MaxRandAlt';
            app.edit49.Tooltip = 'What the starting opacity is in complete training';
            app.edit49.Value = '4';
            app.DistractorsSpinnerLabel = uilabel(app.uipanel1);
            app.DistractorsSpinnerLabel.Text = 'Level';
            app.DistractorsSpinner = uispinner(app.uipanel1);
            app.DistractorsSpinner.Limits = [1 20];
            app.DistractorsSpinner.Tag = 'Starting_opacity';
            app.DistractorsSpinner.Value = 1;
            app.checkbox22 = uicheckbox(app.uipanel1);
            app.checkbox22.Tag = 'EasyTrials';
            app.checkbox22.Tooltip = 'Defines if easier trials should be shown or not';
            app.checkbox22.Text = 'Easy %';
            app.checkbox22.Value = true;
            app.prob_list = uitextarea(app.uipanel1);
            app.prob_list.Tag = 'prob_list';
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Trial Intervals';
            app.uipanel3.Tag = 'uipanel3';
            app.checkbox4 = uicheckbox(app.uipanel3);
            app.checkbox4.Tag = 'Input_ignored';
            app.checkbox4.Text = 'Input ignored';
            app.Pokes_ignored_time = uieditfield(app.uipanel3, 'text');
            app.Pokes_ignored_time.Tag = 'Pokes_ignored_time';
            app.Pokes_ignored_time.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time.Value = '2';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Penalty(s)';
            app.edit6 = uieditfield(app.uipanel3, 'text');
            app.edit6.Tag = 'Penalty_time';
            app.edit6.Tooltip = 'penalty delay in seconds';
            app.edit6.Value = '1';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Intertrial (s)';
            app.edit8 = uieditfield(app.uipanel3, 'text');
            app.edit8.Tag = 'Intertrial_time';
            app.edit8.Tooltip = 'Interval between each trial in seconds';
            app.edit8.Value = '1';
            app.EditField_5Label = uilabel(app.uipanel3);
            app.EditField_5Label.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label.Text = 'Hold still (s):';
            app.HoldStill = uieditfield(app.uipanel3, 'numeric');
            app.HoldStill.Limits = [0 10];
            app.HoldStill.Tag = 'HoldStill';
            app.HoldStill.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Timeout (s)';
            app.edit10 = uieditfield(app.uipanel3, 'text');
            app.edit10.Tag = 'Box_Timeout_after_time';
            app.edit10.Tooltip = 'trial times out and next one begins after how many seconds';
            app.edit10.Value = '0';
            app.SideBiassEditField_2Label = uilabel(app.uipanel3);
            app.SideBiassEditField_2Label.Text = 'SideBias (s):';
            app.SideBiassEditField = uieditfield(app.uipanel3, 'numeric');
            app.SideBiassEditField.Limits = [0.05 0.5];
            app.SideBiassEditField.Tag = 'SideBiasInterval';
            app.SideBiassEditField.Value = 0.25;
            app.checkbox20 = uicheckbox(app.uipanel3);
            app.checkbox20.Tag = 'Dynamic_Reward_Penalty';
            app.checkbox20.Tooltip = 'Automatically increases and decreases reward and penalty time according to last response';
            app.checkbox20.Text = 'Dynamic reward/penalty';
            app.checkbox5 = uicheckbox(app.uipanel3);
            app.checkbox5.Tag = 'Initialize_by_input';
            app.checkbox5.Tooltip = 'start trial by input';
            app.checkbox5.Text = 'initialize trial by input';
            app.checkbox5.Value = true;
            app.checkbox3 = uicheckbox(app.uipanel3);
            app.checkbox3.Tag = 'Repeat_wrong';
            app.checkbox3.Tooltip = 'repeat the same side if last one was wrong';
            app.checkbox3.Text = 'repeat wrong';
            app.PersistcorrectCheckBox = uicheckbox(app.uipanel3);
            app.PersistcorrectCheckBox.Tag = 'Stimulus_PersistCorrect';
            app.PersistcorrectCheckBox.Text = 'Persist correct?';
            app.PersistcorrectCheckBox.Value = true;
            app.EditField_4 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_4.Limits = [0 60];
            app.EditField_4.Tag = 'Stimulus_PersistCorrectInterv';
            app.EditField_4.Value = 1;
            app.PersistincorrectCheckBox = uicheckbox(app.uipanel3);
            app.PersistincorrectCheckBox.Tag = 'Stimulus_PersistIncorrect';
            app.PersistincorrectCheckBox.Text = 'Persist incorrect?';
            app.EditField_3 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_3.Limits = [0 60];
            app.EditField_3.Tag = 'Stimulus_PersistIncorrectInterv';
            app.EditField_3.Value = 1;
            app.text35 = uilabel(app.uipanel3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'binsize:';
            app.edit14 = uieditfield(app.uipanel3, 'text');
            app.edit14.Tag = 'Data_Sbin';
            app.edit14.Tooltip = {'How many trials in a small bin'};
            app.edit14.Value = '10';
            app.edit15 = uieditfield(app.uipanel3, 'text');
            app.edit15.Tag = 'Data_Lbin';
            app.edit15.Tooltip = {'How many small bins in a full bin'};
            app.edit15.Value = '2';
            app.uipanel4 = uipanel(app.GridLayout);
            app.uipanel4.Title = 'Reward / Penalty';
            app.uipanel4.Tag = 'uipanel4';
            app.LButton_2 = uibutton(app.uipanel4, 'push');
            app.LButton_2.ButtonPushedFcn = createCallbackFcn(app, @LButton_2Pushed, true);
            app.LButton_2.Tag = 'LeftValveButton';
            app.LButton_2.Tooltip = {'Press to test Left water reward size'};
            app.LButton_2.Text = 'L';
            app.Lrewardtime = uieditfield(app.uipanel4, 'text');
            app.Lrewardtime.Tag = 'Box_Lrewardtime';
            app.Lrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Lrewardtime.Value = '0.04';
            app.LeftPulse = uieditfield(app.uipanel4, 'numeric');
            app.LeftPulse.Limits = [1 10];
            app.LeftPulse.Tag = 'Box_LeftPulse';
            app.LeftPulse.Tooltip = {'How many pulses from the left water valve'};
            app.LeftPulse.Value = 1;
            app.JuiceCheckBox = uicheckbox(app.uipanel4);
            app.JuiceCheckBox.Tag = 'Box_Juice';
            app.JuiceCheckBox.Tooltip = {'Check this box if the mice are getting fruit juice instead of water.'};
            app.JuiceCheckBox.Text = 'Juice?';
            app.RButton_2 = uibutton(app.uipanel4, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Tag = 'RightValveButton';
            app.RButton_2.Tooltip = {'Press to test Right water reward size'};
            app.RButton_2.Text = 'R';
            app.Rrewardtime = uieditfield(app.uipanel4, 'text');
            app.Rrewardtime.Tag = 'Box_Rrewardtime';
            app.Rrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Rrewardtime.Value = '0.04';
            app.RightPulse = uieditfield(app.uipanel4, 'numeric');
            app.RightPulse.Limits = [1 10];
            app.RightPulse.Tag = 'Box_RightPulse';
            app.RightPulse.Tooltip = {'How many pulses from the rightwater valve'};
            app.RightPulse.Value = 1;
            app.checkbox15 = uicheckbox(app.uipanel4);
            app.checkbox15.Tag = 'Box_Two_ports';
            app.checkbox15.Tooltip = 'use pulses instead of single reward';
            app.checkbox15.Text = '2 valves';
            app.checkbox15.Value = true;
            app.checkbox2 = uicheckbox(app.uipanel4);
            app.checkbox2.Tag = 'Box_Variable_pulses';
            app.checkbox2.Tooltip = {'If selected a random # of pulses between the bounds will be given rather than the same fixed amount each time.'};
            app.checkbox2.Text = {'Variable'; 'Pulses?'};
            app.edit11 = uieditfield(app.uipanel4, 'text');
            app.edit11.Tag = 'Box_Pulse_Min';
            app.edit11.Tooltip = {'Starting number of pulses'};
            app.edit11.Value = '1';
            app.Label = uilabel(app.uipanel4);
            app.Label.Text = '<';
            app.edit11_2 = uieditfield(app.uipanel4, 'text');
            app.edit11_2.Tag = 'Box_PulseMax';
            app.edit11_2.Tooltip = {'Maximum number of pulses'};
            app.edit11_2.Value = '1';
            app.secbwPulsesEditFieldLabel = uilabel(app.uipanel4);
            app.secbwPulsesEditFieldLabel.Text = 'sec b/w Pulses';
            app.SecBwPulse = uieditfield(app.uipanel4, 'numeric');
            app.SecBwPulse.Tag = 'Box_SecBwPulse';
            app.SecBwPulse.Value = 0.2;
            app.AirButton = uibutton(app.uipanel4, 'push');
            app.AirButton.ButtonPushedFcn = createCallbackFcn(app, @AirButtonPushed, true);
            app.AirButton.Tag = 'AirValveButton';
            app.AirButton.Tooltip = {'Press to test Air penalty puff strength.'};
            app.AirButton.Text = 'Air';
            app.puff_valve_1_time = uieditfield(app.uipanel4, 'text');
            app.puff_valve_1_time.Tag = 'Box_AirPuffTime';
            app.puff_valve_1_time.Tooltip = 'How long the vale is open in seconds';
            app.puff_valve_1_time.Value = '0.01';
            app.Puff_pulses = uieditfield(app.uipanel4, 'text');
            app.Puff_pulses.Tag = 'Box_AirPuffPulses';
            app.Puff_pulses.Tooltip = 'How long the vale is open in seconds';
            app.Puff_pulses.Value = '2';
            app.puff_on = uicheckbox(app.uipanel4);
            app.puff_on.Tag = 'Box_Air_Puff_Penalty';
            app.puff_on.Text = 'Air puff?';
            app.puff_on.Value = true;
            app.puff_2_valves = uicheckbox(app.uipanel4);
            app.puff_2_valves.Tag = 'TrainingWrongPuff';
            app.puff_2_valves.Tooltip = {'TRAINING: When the mouse chooses the wrong stimulus, repeated air puffs will be given until the mouse stops choosing that stimulus. The timeout timer will reset while the mouse is in front of the stimulus, (maybe) giving them time to choose the correct stimulus.'};
            app.puff_2_valves.Text = 'Training wrong puff';
            app.AirPuffduringIntertrialCheckBox = uicheckbox(app.uipanel4);
            app.AirPuffduringIntertrialCheckBox.Tag = 'IntertrialPuff';
            app.AirPuffduringIntertrialCheckBox.Tooltip = {'TRAINING: Early training mice will go from Left to Right trying to suck water out of the reward line between trials, instead of starting a new trial. When the ReadyCue is shown this will give an airpuff to discourage that.'};
            app.AirPuffduringIntertrialCheckBox.Text = 'AirPuff during Intertrial';
            app.text86 = uilabel(app.GridLayout);
            app.text86.Tag = 'text86';
            app.text86.Text = 'BehaviorBox App v4.7 - WBS 3/23';
            app.SubjectPanel = uipanel(app.GridLayout);
            app.SubjectPanel.Title = 'Subject';
            app.Investigator = uilabel(app.SubjectPanel);
            app.Investigator.Tag = 'text128';
            app.Investigator.Text = 'Investigator';
            app.Inv = uieditfield(app.SubjectPanel, 'text');
            app.Inv.Tag = 'Investigator';
            app.Inv.Tooltip = 'Name of person conducting the training';
            app.Inv.Value = 'Will';
            app.text13 = uilabel(app.SubjectPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Strain';
            app.Strain = uieditfield(app.SubjectPanel, 'text');
            app.Strain.Tag = 'Strain';
            app.Strain.Tooltip = 'Enter animal strain e.g. Shank3, 16b';
            app.Strain.Value = 'w';
            app.WeightgEditFieldLabel = uilabel(app.SubjectPanel);
            app.WeightgEditFieldLabel.Text = 'Weight';
            app.WeightgEditField = uieditfield(app.SubjectPanel, 'text');
            app.WeightgEditField.Tag = 'Weight';
            app.WeightgEditField.Tooltip = {'Enter the animal''s weight in grams'};
            app.checkbox7 = uicheckbox(app.SubjectPanel);
            app.checkbox7.Tag = 'AutoLoadSave';
            app.checkbox7.Text = 'autoload/save settings';
            app.checkbox7.Value = true;
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = 'Panel';
            app.text65 = uilabel(app.Panel_2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Stimulus:';
            app.Stimulus_type = uidropdown(app.Panel_2);
            app.Stimulus_type.Items = {'Contour crude', 'Contour fine ', 'Square Vs O crude', 'Square Vs O fine', 'unknown', 'XvsO', 'Two Task Contour', 'Cued Images', 'Distract Contour', 'Grating', 'Contour Density', 'BehaviorBox Practice'};
            app.Stimulus_type.Tag = 'Stimulus_type';
            app.Stimulus_type.Tooltip = 'choose stimulus';
            app.Stimulus_type.Value = 'Contour Density';
            app.FlashLinesOnlyCheckBox = uicheckbox(app.Panel_2);
            app.FlashLinesOnlyCheckBox.Tag = 'Stimulus_FlashLinesOnly';
            app.FlashLinesOnlyCheckBox.Text = 'Flash Lines Only';
            app.checkbox19 = uicheckbox(app.Panel_2);
            app.checkbox19.Tag = 'Stimulus_FlashStim';
            app.checkbox19.Text = 'flash';
            app.checkbox19.Value = true;
            app.text121 = uilabel(app.Panel_2);
            app.text121.Tag = 'text121';
            app.text121.Text = 'freq';
            app.edit50 = uieditfield(app.Panel_2, 'text');
            app.edit50.Tag = 'Stimulus_FreqFlashInitial';
            app.edit50.Tooltip = 'Frequency of flashing stimulus';
            app.edit50.Value = '1';
            app.text122 = uilabel(app.Panel_2);
            app.text122.Tag = 'text122';
            app.text122.Text = 'reps';
            app.edit51 = uieditfield(app.Panel_2, 'text');
            app.edit51.Tag = 'Stimulus_RepFlashInitial';
            app.edit51.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51.Value = '1';
            app.OnlyCorrectCheckBox = uicheckbox(app.Panel_2);
            app.OnlyCorrectCheckBox.Tag = 'Stimulus_OnlyFlashCorrect';
            app.OnlyCorrectCheckBox.Tooltip = {'This will only flash the correct choice after the timeout interval.'};
            app.OnlyCorrectCheckBox.Text = 'Only Correct?';
            app.edit50_2 = uieditfield(app.Panel_2, 'text');
            app.edit50_2.Tag = 'Stimulus_FreqFlashAfter';
            app.edit50_2.Tooltip = 'Frequency of flashing stimulus';
            app.edit50_2.Value = '1';
            app.edit51_2 = uieditfield(app.Panel_2, 'text');
            app.edit51_2.Tag = 'Stimulus_RepFlashAfterC';
            app.edit51_2.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51_2.Value = '1';
            app.edit51_3 = uieditfield(app.Panel_2, 'text');
            app.edit51_3.Tag = 'Stimulus_RepFlashAfterW';
            app.edit51_3.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51_3.Value = '1';
            app.StimulusvariablesPanel = uipanel(app.GridLayout);
            app.StimulusvariablesPanel.Title = 'Stimulus variables';
            app.SegmentthicknessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentthicknessEditFieldLabel.Text = 'Segment thickness';
            app.SegmentthicknessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentthicknessEditField.Tag = 'Stimulus_SegThick';
            app.SegmentthicknessEditField.Value = 13;
            app.SegmentlengthEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentlengthEditFieldLabel.Text = 'Segment length';
            app.SegmentlengthEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentlengthEditField.Tag = 'Stimulus_SegLength';
            app.SegmentlengthEditField.Value = 13;
            app.SegmentSpacingEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentSpacingEditFieldLabel.Text = 'Segment Spacing';
            app.SegmentSpacingEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentSpacingEditField.Tag = 'Stimulus_SegSpacing';
            app.SegmentSpacingEditField.Value = 13;
            app.SpotlightbrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SpotlightbrightnessEditFieldLabel.Text = 'Spotlight brightness';
            app.SpotlightbrightnessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SpotlightbrightnessEditField.Limits = [0 1];
            app.SpotlightbrightnessEditField.Tag = 'Stimulus_SpotlightColor';
            app.SpotlightbrightnessEditField.Tooltip = {'0 is black and 1 is white'};
            app.SpotlightbrightnessEditField.Value = 0.2;
            app.BetweenSpotlight_textlabel = uilabel(app.StimulusvariablesPanel);
            app.BetweenSpotlight_textlabel.Tooltip = {'Percentage of the screen space that is left blank between each spotlight'};
            app.BetweenSpotlight_textlabel.Text = 'Between spotlight (%)';
            app.BetweenSpotlight = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BetweenSpotlight.Limits = [0 99];
            app.BetweenSpotlight.Tag = 'Stimulus_BetweenSpotlight';
            app.BetweenSpotlight.Tooltip = {'Percentage of the screen space that is left blank between each spotlight. 33% is the default for the nose poke, use a smaller value for the wheel.'};
            app.LinebrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.LinebrightnessEditFieldLabel.Text = 'Line brightness';
            app.LinebrightnessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.LinebrightnessEditField.Limits = [0 1];
            app.LinebrightnessEditField.Tag = 'Stimulus_LineColor';
            app.LinebrightnessEditField.Tooltip = {'0 is black and 1 is white'};
            app.LinebrightnessEditField.Value = 0.7;
            app.EditField_5Label_2 = uilabel(app.StimulusvariablesPanel);
            app.EditField_5Label_2.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.EditField_5Label_2.Text = 'Flash Color';
            app.FlashColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.FlashColor.Limits = [0 1];
            app.FlashColor.Tag = 'Stimulus_FlashColor';
            app.FlashColor.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.BackgroundEditFieldLabel_2 = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel_2.Text = 'Dim Color';
            app.Stimulus_DimColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_DimColor.Limits = [0 1];
            app.Stimulus_DimColor.Tag = 'Stimulus_DimColor';
            app.Stimulus_DimColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_DimColor.Value = 0.5;
            app.BackgroundEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BackgroundEditField.Limits = [0 1];
            app.BackgroundEditField.Tag = 'Stimulus_BackgroundColor';
            app.BackgroundEditField.Tooltip = {'0 is black and 1 is white'};
            app.BackgroundEditField.Value = 0.5;
            app.ReadyCuecolorLabel = uilabel(app.StimulusvariablesPanel);
            app.ReadyCuecolorLabel.Text = 'ReadyCue color';
            app.EditField_2Label = uilabel(app.StimulusvariablesPanel);
            app.EditField_2Label.Text = 'ReadyCue size';
            app.EditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.Tag = 'ReadyCue_Color';
            app.EditField.Value = 0.7;
            app.EditField_2 = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.EditField_2.Limits = [1 Inf];
            app.EditField_2.Tag = 'ReadyCue_Size';
            app.EditField_2.Value = 12;
            app.SpotlightaroundstimulusCheckBox = uicheckbox(app.StimulusvariablesPanel);
            app.SpotlightaroundstimulusCheckBox.Tag = 'Stimulus_SpotlightToggle';
            app.SpotlightaroundstimulusCheckBox.Tooltip = {'Lickport only, adds a circle around the stimulus.'};
            app.SpotlightaroundstimulusCheckBox.Text = 'Spotlight around stimulus?';
            app.SpotlightaroundstimulusCheckBox.Value = true;
            app.edit21 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit21.Tag = 'Stimulus_size_y';
            app.edit21.Tooltip = {'Height in pixels'};
            app.edit21.Value = '1224';
            app.edit20 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit20.Tag = 'Stimulus_size_x';
            app.edit20.Tooltip = {'Width in pixels'};
            app.edit20.Value = '950';
            app.edit19 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit19.Tag = 'Stimulus_position_x';
            app.edit19.Tooltip = {'X position in pixels'};
            app.edit19.Value = '0';
            app.edit17 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit17.Tag = 'Stimulus_position_y';
            app.edit17.Tooltip = {'Y position in pixels'};
            app.edit17.Value = '0';
            app.text72 = uilabel(app.StimulusvariablesPanel);
            app.text72.Tag = 'text72';
            app.text72.Text = 'com:';
            app.edit22 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit22.Tag = 'Arduino_Com';
            app.edit22.Tooltip = 'com port to use for arduino';
            app.BoxEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BoxEditFieldLabel.Text = 'Box #:';
            app.BoxNum = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BoxNum.Tag = 'BoxNum';
            app.BoxNum.Tooltip = {'Put the Box/Monitor number in this spot. Each mouse should always use the same box.'};
            app.LoadComputerSettingsButton = uibutton(app.StimulusvariablesPanel, 'push');
            app.LoadComputerSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadComputerSettingsButtonPushed, true);
            app.LoadComputerSettingsButton.Tag = 'LoadCompButton';
            app.LoadComputerSettingsButton.Text = 'Load Computer Settings';
            app.SaveComputerSettingsButton = uibutton(app.StimulusvariablesPanel, 'push');
            app.SaveComputerSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveComputerSettingsButtonPushed, true);
            app.SaveComputerSettingsButton.Tag = 'SaveCompButton';
            app.SaveComputerSettingsButton.Text = 'Save Computer Settings';
            app.InputControlPanel = uipanel(app.GridLayout);
            app.InputControlPanel.Title = 'Input Control';
            app.TurnMagEditFieldLabel = uilabel(app.InputControlPanel);
            app.TurnMagEditFieldLabel.Text = 'Turn Mag.';
            app.TurnMagEditField = uieditfield(app.InputControlPanel, 'numeric');
            app.TurnMagEditField.Tag = 'TurnMag';
            app.TurnMagEditField.Value = 1000;
            app.RoundupchoiceCheckBox = uicheckbox(app.InputControlPanel);
            app.RoundupchoiceCheckBox.Tag = 'RoundUp';
            app.RoundupchoiceCheckBox.Tooltip = {'Choose to accept the mouse''s choice at timeout if they turn the wheel *almost* to the choice.'};
            app.RoundupchoiceCheckBox.Text = 'Round up choice';
            app.RoundUpEditFieldLabel = uilabel(app.InputControlPanel);
            app.RoundUpEditFieldLabel.Text = 'Round Up (%)';
            app.RoundUpEditField = uieditfield(app.InputControlPanel, 'numeric');
            app.RoundUpEditField.Limits = [0 110];
            app.RoundUpEditField.Tag = 'RoundUpVal';
            app.RoundUpEditField.Tooltip = {'Choose the percentage threshold to accept the mouse''s choice if they do not turn the wheel fully.'};
            app.RoundUpEditField.Value = 95;
            app.FinishlineCheckBox = uicheckbox(app.InputControlPanel);
            app.FinishlineCheckBox.Tag = 'Stimulus_FinishLine';
            app.FinishlineCheckBox.Tooltip = {'DIsplay a marker that will indicate how far the mouse has to turn the wheel to input a choice. Based on stimulus variables.'};
            app.FinishlineCheckBox.Text = 'Finish line?';
            app.IntertrialMalCheckBox = uicheckbox(app.InputControlPanel);
            app.IntertrialMalCheckBox.Tag = 'IntertrialMalCancel';
            app.IntertrialMalCheckBox.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCheckBox.Text = 'Intertrial Mal';
            app.IntertrialMalCheckBox.Value = true;
            app.EditField_5 = uieditfield(app.InputControlPanel, 'numeric');
            app.EditField_5.Tag = 'IntertrialMalSec';
            app.EditField_5.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.EditField_5.Value = 1;
            app.DuringTrialEditFieldLabel = uilabel(app.InputControlPanel);
            app.DuringTrialEditFieldLabel.Text = 'Input delay';
            app.InputDelayStart = uieditfield(app.InputControlPanel, 'numeric');
            app.InputDelayStart.Limits = [0 2];
            app.InputDelayStart.Tag = 'Input_Delay_Start';
            app.InputDelayStart.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.InputDelayStart.Value = 0.5;
            app.EditField_6Label = uilabel(app.InputControlPanel);
            app.EditField_6Label.Text = 'Hold Still Thresh';
            app.EditField_6 = uieditfield(app.InputControlPanel, 'numeric');
            app.EditField_6.Limits = [0 500];
            app.EditField_6.Tag = 'Hold_Still_Thresh';
            app.EditField_6.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.EditField_6.Value = 10;
            app.InputDelayRespond = uieditfield(app.InputControlPanel, 'numeric');
            app.InputDelayRespond.Limits = [0 2];
            app.InputDelayRespond.Tag = 'Input_Delay_Respond';
            app.InputDelayRespond.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.InputDelayRespond.Value = 0.1;
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.NotesTab.Title = 'Notes';
            app.NotesTab.Tag = 'INO';
            app.TextArea2 = uitextarea(app.NotesTab);
            app.TextArea2.Tag = 'NotesText';
            app.TextArea2.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.MsgBox = uitextarea(app.OutputTab);
            app.MsgBox.Tag = 'MsgBox';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceTab.Tag = 'PerfTab';
            app.TimersTab = uitab(app.TabGroup);
            app.TimersTab.Title = 'Timers';
            app.AllTimeTab = uitab(app.TabGroup);
            app.AllTimeTab.Title = 'All Time';
            app.WeightWaterTab = uitab(app.TabGroup);
            app.WeightWaterTab.Title = 'Weight/Water';
            app.OtherUnusedTab = uitab(app.TabGroup);
            app.OtherUnusedTab.Title = 'Other / Unused';
            app.uipanel11 = uipanel(app.OtherUnusedTab);
            app.uipanel11.Title = 'Orientation Tuning';
            app.uipanel11.Tag = 'uipanel11';
            app.text79 = uilabel(app.uipanel11);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Repeats:';
            app.text81 = uilabel(app.uipanel11);
            app.text81.Tag = 'text81';
            app.text81.Text = 'Duration:';
            app.text82 = uilabel(app.uipanel11);
            app.text82.Tag = 'text82';
            app.text82.Text = 'Orientations:';
            app.text83 = uilabel(app.uipanel11);
            app.text83.Tag = 'text83';
            app.text83.Text = 'Interval:';
            app.text84 = uilabel(app.uipanel11);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Bar size:';
            app.text85 = uilabel(app.uipanel11);
            app.text85.Tag = 'text85';
            app.text85.Text = 'Frequency:';
            app.pushbutton19 = uibutton(app.uipanel11, 'push');
            app.pushbutton19.ButtonPushedFcn = createCallbackFcn(app, @pushbutton19_Callback, true);
            app.pushbutton19.Tag = 'Orientation';
            app.pushbutton19.Tooltip = 'show orientation tuning only (no input required)';
            app.pushbutton19.Text = 'Orientation Tuning Only';
            app.edit25 = uieditfield(app.uipanel11, 'text');
            app.edit25.Tag = 'Ori_Repeats';
            app.edit25.Tooltip = 'how many repeats of all orientations';
            app.edit25.Value = '2';
            app.edit26 = uieditfield(app.uipanel11, 'text');
            app.edit26.Tag = 'Ori_Duration';
            app.edit26.Tooltip = 'how long to show each orientation';
            app.edit26.Value = '2';
            app.popupmenu4 = uidropdown(app.uipanel11);
            app.popupmenu4.Items = {'4', '8', '16'};
            app.popupmenu4.Tag = 'Ori_Orientations';
            app.popupmenu4.Tooltip = 'total number of equally spaced orientations';
            app.popupmenu4.Value = '8';
            app.edit28 = uieditfield(app.uipanel11, 'text');
            app.edit28.Tag = 'Ori_Interval';
            app.edit28.Tooltip = 'interval between each orientation';
            app.edit28.Value = '2';
            app.checkbox13 = uicheckbox(app.uipanel11);
            app.checkbox13.Tag = 'Ori_Random';
            app.checkbox13.Tooltip = 'randomize orientations';
            app.checkbox13.Text = 'random';
            app.edit29 = uieditfield(app.uipanel11, 'text');
            app.edit29.Tag = 'Ori_Bar_Size';
            app.edit29.Tooltip = 'size gratings';
            app.edit29.Value = '170';
            app.edit30 = uieditfield(app.uipanel11, 'text');
            app.edit30.Tag = 'Ori_Frequency';
            app.edit30.Tooltip = 'temporal frequency of grating';
            app.edit30.Value = '0.5';
            app.edit32 = uieditfield(app.uipanel11, 'text');
            app.edit32.Tag = 'Stimulus_Orient';
            app.edit32.Tooltip = {'Orientation of stimulus in degrees'};
            app.edit32.Value = '0';
            app.uipanel12 = uipanel(app.OtherUnusedTab);
            app.uipanel12.Title = 'Difficulty Adjust';
            app.uipanel12.Tag = 'uipanel12';
            app.GridLayout4 = uigridlayout(app.uipanel12);
            app.popupmenu9 = uidropdown(app.GridLayout4);
            app.popupmenu9.Items = {'Inc/dec after trials', 'Step up after trials', 'Random diff in range'};
            app.popupmenu9.Tag = 'DiffAdjustMethod';
            app.popupmenu9.Value = 'Inc/dec after trials';
            app.checkbox1 = uicheckbox(app.GridLayout4);
            app.checkbox1.Tag = 'Raise_bg_with_perf';
            app.checkbox1.Text = 'inc diff after trials:';
            app.edit23 = uieditfield(app.GridLayout4, 'text');
            app.edit23.Tag = 'RaiseDiffAfterBins';
            app.edit23.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit23.Value = '10';
            app.checkbox18 = uicheckbox(app.GridLayout4);
            app.checkbox18.Tag = 'Lower_bg_with_perf';
            app.checkbox18.Text = 'dec diff after trials:';
            app.edit35 = uieditfield(app.GridLayout4, 'text');
            app.edit35.Tag = 'LowerDiffAfterBins';
            app.edit35.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit35.Value = '10';
            app.text94 = uilabel(app.GridLayout4);
            app.text94.Tag = 'text94';
            app.text94.Text = 'inc thres:';
            app.edit37 = uieditfield(app.GridLayout4, 'text');
            app.edit37.Tag = 'RaiseThres';
            app.edit37.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit37.Value = '0.65';
            app.text95 = uilabel(app.GridLayout4);
            app.text95.Tag = 'text95';
            app.text95.Text = 'dec thres:';
            app.edit38 = uieditfield(app.GridLayout4, 'text');
            app.edit38.Tag = 'LowerThres';
            app.edit38.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit38.Value = '0.5';
            app.text96 = uilabel(app.GridLayout4);
            app.text96.Tag = 'text96';
            app.text96.Text = 'Adj Method:';
            app.text97 = uilabel(app.GridLayout4);
            app.text97.Tag = 'text97';
            app.text97.Text = 'step up after trials:';
            app.edit39 = uieditfield(app.GridLayout4, 'text');
            app.edit39.Tag = 'StepUpAfter';
            app.edit39.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit39.Value = '20';
            app.text98 = uilabel(app.GridLayout4);
            app.text98.Tag = 'text98';
            app.text98.Text = 'step:';
            app.edit40 = uieditfield(app.GridLayout4, 'text');
            app.edit40.Tag = 'RandomMax';
            app.edit40.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit40.Value = '1';
            app.text99 = uilabel(app.GridLayout4);
            app.text99.Tag = 'text99';
            app.text99.Text = 'random max:';
            app.edit41 = uieditfield(app.GridLayout4, 'text');
            app.edit41.Tag = 'RandomStep';
            app.edit41.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit41.Value = '0.1';
            app.text100 = uilabel(app.GridLayout4);
            app.text100.Tag = 'text100';
            app.text100.Text = 'random min:';
            app.edit42 = uieditfield(app.GridLayout4, 'text');
            app.edit42.Tag = 'RandomMin';
            app.edit42.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit42.Value = '0.4';
            app.text123 = uilabel(app.GridLayout4);
            app.text123.Tag = 'text123';
            app.text123.Text = 'sub bin size:';
            app.edit52 = uieditfield(app.GridLayout4, 'text');
            app.edit52.Tag = 'sub_bin_size';
            app.edit52.Tooltip = 'size of sub bin (to measure std threshold)';
            app.edit52.Value = '5';
            app.text124 = uilabel(app.GridLayout4);
            app.text124.Tag = 'text124';
            app.text124.Text = 'std thres:';
            app.edit53 = uieditfield(app.GridLayout4, 'text');
            app.edit53.Tag = 'stdthreshold';
            app.edit53.Tooltip = 'standard deviation threshold to change difficulty';
            app.edit53.Value = '0.5';
            app.uipanel13 = uipanel(app.OtherUnusedTab);
            app.uipanel13.Title = '2 tasks';
            app.uipanel13.Tag = 'uipanel13';
            app.GridLayout2 = uigridlayout(app.uipanel13);
            app.text102 = uilabel(app.GridLayout2);
            app.text102.Tag = 'text102';
            app.text102.Text = 'show cue (s):';
            app.edit44 = uieditfield(app.GridLayout2, 'text');
            app.edit44.Tag = 'CueDuration';
            app.edit44.Tooltip = 'how many single choices are used to calculate % performance';
            app.edit44.Value = '2';
            app.text103 = uilabel(app.GridLayout2);
            app.text103.Tag = 'text103';
            app.text103.Text = 'wait after cue (s):';
            app.edit45 = uieditfield(app.GridLayout2, 'text');
            app.edit45.Tag = 'WaitAfterCue';
            app.edit45.Tooltip = 'how many single choices are used to calculate % performance';
            app.edit45.Value = '1';
            app.uipanel8 = uipanel(app.OtherUnusedTab);
            app.uipanel8.Title = 'Input training';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout3 = uigridlayout(app.uipanel8);
            app.checkbox6 = uicheckbox(app.GridLayout3);
            app.checkbox6.Tag = 'Show_two_bars';
            app.checkbox6.Tooltip = 'Show two bars for input training only';
            app.checkbox6.Text = 'show two training bars';
            app.checkbox6.Value = true;
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Unlimited Rewards', 'Only Correct or Timeout', 'None', 'Go/No-Go Practice', 'Wheel Practice Early', 'Wheel Practice Late', 'Wheel Practice - No wrong choice', 'NosePoke Training 1', 'NosePoke Training 2', 'NosePoke Training 3', 'NosePoke Training 4', 'NosePoke Training 5'};
            app.DropDown.Tag = 'TrainingChoices';
            app.DropDown.Tooltip = {'Choose Special Rules for training new mice.'};
            app.DropDown.Value = 'None';
            app.FlashifwrongCheckBox = uicheckbox(app.GridLayout3);
            app.FlashifwrongCheckBox.Tag = 'Stimulus_TrainingFlashifWrong';
            app.FlashifwrongCheckBox.Text = 'Flash if wrong?';
            app.pushbutton3 = uibutton(app.GridLayout3, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'Train';
            app.pushbutton3.Tooltip = 'Start input training (either choice will get reward)';
            app.pushbutton3.Text = 'Training Mode';
            app.checkbox17 = uicheckbox(app.OtherUnusedTab);
            app.checkbox17.Tag = 'animate_stim';
            app.checkbox17.Text = 'animate';
            app.text92 = uilabel(app.OtherUnusedTab);
            app.text92.Tag = 'text92';
            app.text92.Text = 'Animate Speed';
            app.edit34 = uieditfield(app.OtherUnusedTab, 'text');
            app.edit34.Tag = 'animate_speed';
            app.edit34.Tooltip = 'What the starting opacity is in complete training';
            app.edit34.Value = '5';
            app.SoundonlyonerrorCheckBox = uicheckbox(app.OtherUnusedTab);
            app.SoundonlyonerrorCheckBox.Tag = 'ErrorSoundOnly';
            app.SoundonlyonerrorCheckBox.Text = 'Sound only on error';
            app.checkbox9 = uicheckbox(app.OtherUnusedTab);
            app.checkbox9.Tag = 'Play_sound';
            app.checkbox9.Tooltip = 'use sound for start and correct trials';
            app.checkbox9.Text = 'Sounds?';
            app.checkbox8 = uicheckbox(app.OtherUnusedTab);
            app.checkbox8.Tag = 'Ext_trigger';
            app.checkbox8.Tooltip = 'start experiment by an external trigger';
            app.checkbox8.Text = 'ext trigger';
            app.checkbox21 = uicheckbox(app.OtherUnusedTab);
            app.checkbox21.Tag = 'CueOrNot';
            app.checkbox21.Tooltip = 'use to define if cue should be presented or not';
            app.checkbox21.Text = 'cue';
            app.EquipmentPanel = uipanel(app.OtherUnusedTab);
            app.EquipmentPanel.Title = 'Equipment';
            app.OpenLButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenLButton.ValueChangedFcn = createCallbackFcn(app, @OpenLButtonValueChanged, true);
            app.OpenLButton.Tag = 'OpenL';
            app.OpenLButton.Text = 'Open L';
            app.OpenRButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenRButton.ValueChangedFcn = createCallbackFcn(app, @OpenRButtonValueChanged, true);
            app.OpenRButton.Tag = 'OpenR';
            app.OpenRButton.Text = 'Open R';
            app.OpenBothButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenBothButton.ValueChangedFcn = createCallbackFcn(app, @OpenBothButtonValueChanged, true);
            app.OpenBothButton.Tag = 'OpenBoth';
            app.OpenBothButton.Text = 'Open Both';
            app.RESETALLButton_2 = uibutton(app.EquipmentPanel, 'state');
            app.RESETALLButton_2.ValueChangedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.RESETALLButton_2.Tag = 'ResetAll';
            app.RESETALLButton_2.Text = 'RESET ALL';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorBox_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @BehaviorBox_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end